classdef AppAverageLength < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        OpenButton                    matlab.ui.control.Button
        GoButton                      matlab.ui.control.Button
        UploadImageLabel              matlab.ui.control.Label
        MagnitudeDropDownLabel        matlab.ui.control.Label
        MagnitudeDropDown             matlab.ui.control.DropDown
        SelectedImageLabel            matlab.ui.control.Label
        SelectedImageEditField        matlab.ui.control.EditField
        NumGerminatedSporesEditFieldLabel  matlab.ui.control.Label
        NumGerminatedSporesEditField  matlab.ui.control.NumericEditField
        AverageLengthEditFieldLabel   matlab.ui.control.Label
        AverageLengthEditField        matlab.ui.control.NumericEditField
        ComputingLabel                matlab.ui.control.Label
        CommentsTextAreaLabel         matlab.ui.control.Label
        CommentsTextArea              matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        value
        path
        choose
        Img
        Comment
        GerminatedSpores
        AverageLength
        fullname
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MagnitudeDropDown
        function MagnitudeDropDownValueChanged(app, event)
            app.value = app.MagnitudeDropDown.Value;
            if app.value=="X40"
                %app.path='D:\Spores\Images\X40\G5002 T30 X40.tif';
                app.choose=2;
            elseif app.value=="X100"
                %app.path='D:\Spores\Images\X100\G5002 T30 X100.tif';
                app.choose=1;
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [filename,filepath]=uigetfile({'*.tif'},'Select File to Open');
            app.fullname=[filepath,filename];
            app.Img=imageDatastore(app.fullname);
            app.Img=readimage(app.Img,1);
            imshow(app.Img,'Parent',app.UIAxes);
            
            app.NumGerminatedSporesEditField.Value=0;
            app.AverageLengthEditField.Value=0;
            app.CommentsTextArea.Value='';
            app.SelectedImageEditField.Value=filename;
            
            set(app.GoButton,'Enable','on');
            app.OpenButton.Enable='off';
            drawnow;
            app.AverageLengthEditField.Enable='off';
            drawnow;
            app.NumGerminatedSporesEditField.Enable='off';
            drawnow;
            app.NumGerminatedSporesEditFieldLabel.Text='Num. Germinated Spores';
            drawnow;
            app.CommentsTextArea.Enable='off';
            drawnow;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.GoButton.Enable='off';
            drawnow;
            app.ComputingLabel.Visible='on';
            drawnow;
            Singletons=[];    
            SporesCenters=[];               
            SporesRadii=[]; 
            if app.choose==1                
                labI=rgb2lab(app.Img);
                ab=labI(:,:,1:2);
                ab=im2single(ab);
                nColors=2;
                pixel_labels=imsegkmeans(ab,nColors,'NumAttempts',3);
                Bin=imbinarize(pixel_labels);  
                Bin1=imbinarize(pixel_labels);
                
                [count,pixels]=imhist(Bin);
                black=count(1);
                white=count(2);
                ratio=white/black;
             
                Im=rgb2gray(app.Img);
    
                if ratio<0.1   %check if there are germinative tube
                %delete touching-edge germinative tubes, but keep everithing if 
                %removing the germinative tubes that touch the edges implies
                %removing too much;
                
                %different way to extract binary image: works better to remove edge-touching spores
                    Im=mat2gray(Im);
                    Im=imadjust(Im);
                    filtImage = stdfilt(Im, ones(25));
                    filtImage=imcomplement(filtImage);
                    filteredImage=imbinarize(filtImage,'adaptive','Sensitivity',0.7);
                    Bin_1=imcomplement(filteredImage);
                    ConnComp_1=bwconncomp(Bin_1,4);
                    N_1=ConnComp_1.NumObjects;
                    stat_1=regionprops(ConnComp_1,'all');
                    Threshold=(30*white)/100;
                    for j=1:N_1
                        x=stat_1(j).PixelList(:,1);
                        y=stat_1(j).PixelList(:,2);
                        if stat_1(j).Area<=2000            %find the isolated (non germinated) spores
                            Singletons(end+1)=j;           %add the detected non germinated spores to list of 'Singletons'
                            Bin(y,x)=0;                    %delete them from binary image --> only germinated spores left
                            Bin1(y,x)=0;
                        end
                        if  stat_1(j).Extent>=0.7            %find the isolated (non germinated) spores (leftovers from previous equivalent operation)
                            Bin(y,x)=0;
                            Bin1(y,x)=0;
                        end
                    end
                    Cleaned=imclearborder(Bin_1,8);
                    Edges=Bin_1-Cleaned;
                    Edges=imfill(Edges,'holes');
                    Mask=logical(Edges);
                    Bin(Mask)=0;                    
                    Bin=imclearborder(Bin,8);
                    [CountP1,Pixel1]=imhist(Bin);
                    White1=CountP1(2);
                    if White1<Threshold                    %if removed too much than keep everything
                        Bin=Bin1;
                    else
                        app.Img(Mask)=256;
                    end
                
                    ConnComp=bwconncomp(Bin,4);
                    N=ConnComp.NumObjects;        
                    stat=regionprops(ConnComp,'all');  
       
                    for j=1:N                            %for each detected connected component
                        if  stat(j).Area<=300            %find the isolated (non germinated) spores (leftovers from previous equivalent operation)
                            x=stat(j).PixelList(:,1);    
                            y=stat(j).PixelList(:,2);
                            Bin(y,x)=0;                  %remove leftovers
                        end
                    end
                    
                    [Centers,Radii]=imfindcircles(Bin,[11 20],'Sensitivity',0.9);     %look for circles(spores) in binary image in which non germinated spores have been removed  
                    
                    [columns rows]=meshgrid(1:size(Bin,2),1:size(Bin,1));
                    for k=1:numel(Radii)
                        x=floor(Centers(k,1));          
                        y=floor(Centers(k,2));
                        array2D = (rows- y).^2+ (columns - x).^2<(Radii(k)-5)^2;
                        if Bin(array2D)==1                       %if the circle contains only white pixels it means that it is really a spore
                            SporesCenters=[SporesCenters;[x y]]; %save location of detected spore
                            SporesRadii(end+1)=Radii(k); 
                            array2D = (rows- y).^2+ (columns - x).^2<(Radii(k))^2;
                            Bin(array2D)=0;                      %hide pixels corresponding to spores (makes easier to compute length of tubes)
                        end
                    end 
                    %repeat circles detection with different radius range: find more
                    %circles in this way (don't know why). Keep only one copy of any double
                    [Centers1,Radii1]=imfindcircles(Bin,[11 25],'Sensitivity',0.9);
                    for k=1:numel(Radii1)
                        xx=floor(Centers1(k,1));
                        yy=floor(Centers1(k,2)); 
                        array2D = (rows- yy).^2+ (columns - xx).^2<(Radii1(k)-5)^2;
                        if  isempty(SporesCenters)==0 & numel(find(SporesCenters==[xx yy]))==0 & Bin(array2D)==1
                            SporesCenters=[SporesCenters;[xx yy]];
                            SporesRadii(end+1)=Radii1(k);               
                            array2D = (rows- yy).^2+ (columns - xx).^2<(Radii1(k))^2;
                            Bin(array2D)=0;
                        end
                    end                    
                    imshow(app.Img,'Parent',app.UIAxes);
                    hold(app.UIAxes,'on');
                    for k=1:numel(SporesRadii)
                        x=SporesCenters(k,1)-SporesRadii(k);
                        y=SporesCenters(k,2)-SporesRadii(k);
                        pos=[x y SporesRadii(k) SporesRadii(k)];
                        rectangle('Position',pos,'Curvature',[1 1],'Parent',app.UIAxes,'EdgeColor','r','LineWidth',3);
                    end
                   
                    app.GerminatedSpores=numel(SporesRadii);  %how many germinated spores have been detected
                    [CountPixels,PixelValue]=imhist(Bin);
                    AreaGerminativeTubes=CountPixels(2);                 
                    AverageTubeWidth=15;
                    TotalLength=AreaGerminativeTubes/AverageTubeWidth;
                    app.AverageLength=TotalLength/app.GerminatedSpores;
                    if White1<Threshold
                        app.Comment='Edge-touching tubes have not been removed.';
                    else 
                        app.Comment='';
                    end  
                else
                    [Centers,Radii]=imfindcircles(Im,[6 16],'Sensitivity',0.8,'EdgeThreshold',0.1,'ObjectPolarity','dark');                                       
                    imshow(app.Img,'Parent',app.UIAxes);
                    hold(app.UIAxes,'on');
                    for k=1:numel(Radii)
                        x=Centers(k,1)-Radii(k);
                        y=Centers(k,2)-Radii(k);
                        pos=[x y Radii(k) Radii(k)];
                        rectangle('Position',pos,'Curvature',[1 1],'Parent',app.UIAxes,'EdgeColor','r','LineWidth',3);
                    end
                    app.GerminatedSpores=numel(Radii);
                    app.Comment='No germinated spores have been detected.';
                    app.AverageLength=0;
                    app.NumGerminatedSporesEditFieldLabel.Text='Num. Detected Spores';                    
                end      
                app.AverageLengthEditField.Enable='on';
                app.NumGerminatedSporesEditField.Enable='on';
                app.AverageLengthEditField.Value=app.AverageLength;
                app.NumGerminatedSporesEditField.Value=app.GerminatedSpores;
            elseif app.choose==2
                Igray=rgb2gray(app.Img);
                se=strel('disk',25);
                background=imclose(Igray,se);
                Im=background-Igray;
                Im=imcomplement(Im);
                Bin=imbinarize(Im);
                Bin=imcomplement(Bin);
                Bin1=Bin;
                                
                IM=imadjust(Im);
                IM=mat2gray(IM);
                IM=imadjust(IM);
                filteredImage = stdfilt(IM, ones(9));
                filteredImage=imcomplement(filteredImage);
                filteredImage=imbinarize(filteredImage,'adaptive','Sensitivity',0.6);
                Bin_1=imcomplement(filteredImage);
                
                [count,pixels]=imhist(Bin_1);
                black=count(1);
                white=count(2);
                ratio=white/black;
                
                    
                if ratio<0.5 
                    ConnComp1=bwconncomp(Bin_1,4);
                    N_1=ConnComp1.NumObjects;
                    stat_1=regionprops(ConnComp1,'all');
                    [count,pixels]=imhist(Bin);
                    white=count(2);
                    Threshold=(30*white)/100;
                    for j=1:N_1
                        x=stat_1(j).PixelList(:,1);
                        y=stat_1(j).PixelList(:,2);
                        if stat_1(j).Area<=1000            %find the isolated (non germinated) spores
                            Singletons(end+1)=j;           %add the detected non germinated spores to list of 'Singletons'
                            Bin(y,x)=0;                    %delete them from binary image --> only germinated spores left
                            Bin1(y,x)=0;
                        end
                        if  stat_1(j).Extent>=0.7     
                            Bin(y,x)=0;
                            Bin1(y,x)=0;
                        end
                    end
                    Cleaned=imclearborder(Bin_1,8);
                    Edges=Bin_1-Cleaned;
                    Edges=imfill(Edges,'holes');
                    Mask=logical(Edges);
                    Bin(Mask)=0;
                    Bin=imclearborder(Bin,8);
                    [CountP1,Pixel1]=imhist(Bin);
                    White1=CountP1(2);
                    if White1<Threshold                    %if removed too much than keep everything
                        Bin=Bin1;
                    else
                        app.Img(Mask)=256;
                    end
                    
                    ConnComp=bwconncomp(Bin,4);
                    N=ConnComp.NumObjects;
                    stat=regionprops(ConnComp,'all');
                    
                    for j=1:N                            %for each detected connected component
                        if  stat(j).Area<=400            %find the isolated (non germinated) spores (leftovers from previous equivalent operation)
                            x=stat(j).PixelList(:,1);
                            y=stat(j).PixelList(:,2);
                            Bin(y,x)=0;                  %remove leftovers
                        end
                    end
                    
                    ConnComp=bwconncomp(Bin,4);
                    N=ConnComp.NumObjects;
                    stat=regionprops(ConnComp,'all');
                    for j=1:N                            %for each detected connected component
                        if  stat(j).Extent>=0.7            %find the isolated (non germinated) spores (leftovers from previous equivalent operation)
                            x=stat(j).PixelList(:,1);
                            y=stat(j).PixelList(:,2);
                            Bin(y,x)=0;
                        end
                    end
                    [Centers,Radii]=imfindcircles(Bin,[6 12],'Sensitivity',0.93,'EdgeThreshold',0.89,'ObjectPolarity','bright');  %look for circles(spores) in binary image in which non germinated spores have been removed
                    imshow(app.Img,'Parent',app.UIAxes);
                    hold(app.UIAxes,'on');
                    [columns rows]=meshgrid(1:size(Bin,2),1:size(Bin,1));
                    for k=1:numel(Radii)
                        x=floor(Centers(k,1));
                        y=floor(Centers(k,2));
                        array2D = (rows- y).^2+ (columns - x).^2<(Radii(k))^2;
                        Bin(array2D)=0;                      %hide pixels corresponding to spores (makes easier to compute length of tubes)
                        x=Centers(k,1)-Radii(k);
                        y=Centers(k,2)-Radii(k);
                        pos=[x y Radii(k) Radii(k)];
                        rectangle('Position',pos,'Curvature',[1 1],'Parent',app.UIAxes,'EdgeColor','r','LineWidth',2);
                    end
                    
                    app.GerminatedSpores=numel(Radii);  %how many germinated spores have been detected
                    [CountPixels,PixelValue]=imhist(Bin);
                    AreaGerminativeTubes=CountPixels(2);
                    AverageTubeWidth=5;
                    TotalLength=AreaGerminativeTubes/AverageTubeWidth;
                    app.AverageLength=TotalLength/app.GerminatedSpores;
                    if White1<Threshold
                        app.Comment='Edge-touching tubes have not been removed.';
                    else
                        app.Comment='';
                    end
                else                    
                    [Centers,Radii]=imfindcircles(I,[6 16],'Sensitivity',0.8,'EdgeThreshold',0.1,'ObjectPolarity','dark');imshow(app.Img,'Parent',app.UIAxes);                                       
                    imshow(app.Img,'Parent',app.UIAxes);
                    hold(app.UIAxes,'on');
                    for k=1:numel(Radii)
                        x=Centers(k,1)-Radii(k);
                        y=Centers(k,2)-Radii(k);
                        pos=[x y Radii(k) Radii(k)];
                        rectangle('Position',pos,'Curvature',[1 1],'Parent',app.UIAxes,'EdgeColor','r','LineWidth',3);
                    end
                    app.GerminatedSpores=numel(Radii);
                    app.Comment='No germinated spores have been detected.';
                    app.AverageLength=0;
                    app.NumGerminatedSporesEditFieldLabel.Text='Num. Detected Spores';
                end
                 app.AverageLengthEditField.Enable='on';
                 app.NumGerminatedSporesEditField.Enable='on';
                 app.AverageLengthEditField.Value=app.AverageLength;
                 app.NumGerminatedSporesEditField.Value=app.GerminatedSpores;

            end
            app.CommentsTextArea.Enable='on';
            app.CommentsTextArea.Value=app.Comment;
            app.OpenButton.Enable='on';
            drawnow;
            app.ComputingLabel.Visible='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.UploadImageLabel = uilabel(app.UIFigure);
            app.UploadImageLabel.Text = 'Upload Image:';
            app.MagnitudeDropDownLabel = uilabel(app.UIFigure);
            app.MagnitudeDropDownLabel.Text = 'Magnitude';
            app.MagnitudeDropDown = uidropdown(app.UIFigure);
            app.MagnitudeDropDown.Items = {'Select...', 'X100', 'X40'};
            app.MagnitudeDropDown.ValueChangedFcn = createCallbackFcn(app, @MagnitudeDropDownValueChanged, true);
            app.MagnitudeDropDown.Value = 'Select...';
            app.SelectedImageLabel = uilabel(app.UIFigure);
            app.SelectedImageLabel.Text = 'Selected Image:';
            app.SelectedImageEditField = uieditfield(app.UIFigure, 'text');
            app.SelectedImageEditField.Editable = 'off';
            app.NumGerminatedSporesEditFieldLabel = uilabel(app.UIFigure);
            app.NumGerminatedSporesEditFieldLabel.Text = 'Num. Germinated Spores';
            app.NumGerminatedSporesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumGerminatedSporesEditField.Editable = 'off';
            app.NumGerminatedSporesEditField.Enable = 'off';
            app.AverageLengthEditFieldLabel = uilabel(app.UIFigure);
            app.AverageLengthEditFieldLabel.Text = 'Average Length';
            app.AverageLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.AverageLengthEditField.Editable = 'off';
            app.AverageLengthEditField.Enable = 'off';
            app.ComputingLabel = uilabel(app.UIFigure);
            app.ComputingLabel.Visible = 'off';
            app.ComputingLabel.Text = 'Computing...';
            app.CommentsTextAreaLabel = uilabel(app.UIFigure);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.UIFigure);
            app.CommentsTextArea.Editable = 'off';
            app.CommentsTextArea.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppAverageLength

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end