classdef AppAverageLengthNew2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OpenButton                      matlab.ui.control.Button
        GoButton                        matlab.ui.control.Button
        UploadImagesLabel               matlab.ui.control.Label
        MagnitudeDropDownLabel          matlab.ui.control.Label
        MagnitudeDropDown               matlab.ui.control.DropDown
        SelectedFolderEditFieldLabel    matlab.ui.control.Label
        SelectedFolderEditField         matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        CommentsTextAreaLabel           matlab.ui.control.Label
        CommentsTextArea                matlab.ui.control.TextArea
        UIAxes                          matlab.ui.control.UIAxes
        AverageLengthEditFieldLabel     matlab.ui.control.Label
        AverageLengthEditField          matlab.ui.control.EditField
        AverageNumberofGerminatedSporesEditFieldLabel  matlab.ui.control.Label
        AverageNumberofGerminatedSporesEditField  matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        AverageLengthsButton            matlab.ui.control.RadioButton
        NumberofGerminatedSporesButton  matlab.ui.control.RadioButton
        StopButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        value
        path
        choose
        ImgDatastore
        selectedButton
        Comment
        GerminatedSpores
        AverageLength
        filepath
        Lengths
        NumSpores
        NumNotGerminatedSpores   
        NotRemoved
        NotRemovedLengths
        NotGerminated
        NumImages
        Images
        NotRemovedSpores
        stop
        label
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MagnitudeDropDown
        function MagnitudeDropDownValueChanged(app, event)
            app.value = app.MagnitudeDropDown.Value;
            if app.value=="X40"
                %app.path='D:\Spores\Images\X40\G5002 T30 X40.tif';
                app.choose=2;
                     app.Label_2.Visible='on';
                     app.Label_2.FontColor=[1 0.41 0.16];
                     Warning='Warning: analysis for images with magnitude X40 is not recommended due to low performance.';
                     app.Label_2.Text=Warning;
                     drawnow;
            elseif app.value=="X100"
                %app.path='D:\Spores\Images\X100\G5002 T30 X100.tif';
                app.choose=1;
                app.Label_2.Visible='off';
                drawnow;
            end
            app.StopButton.Enable='off';
            drawnow;
            app.StopButton.Visible='off';
            drawnow;
            app.GoButton.Visible='on';
            drawnow;
            app.GoButton.Enable='off';
            drawnow;
            app.CommentsTextArea.Value='';
            app.CommentsTextArea.Enable='off';
            drawnow;
            app.AverageLengthsButton.Enable='off';
            drawnow;
            app.NumberofGerminatedSporesButton.Enable='off';
            drawnow;
            app.AverageLengthEditField.Value='';
            app.AverageLengthEditField.Enable='off';
            drawnow;
            app.AverageNumberofGerminatedSporesEditField.Value='';
            app.AverageNumberofGerminatedSporesEditField.Enable='off';
            drawnow;
            app.SelectedFolderEditField.Value='';
            cla(app.UIAxes);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            cla(app.UIAxes);  
            app.StopButton.Enable='off';
            drawnow;
            app.StopButton.Visible='off';
            drawnow;
            app.GoButton.Visible='on';
            drawnow;
            app.AverageLengthsButton.Enable='off';
            drawnow;
            app.NumberofGerminatedSporesButton.Enable='off';
            drawnow;
            app.AverageLengthEditField.Value='';
            app.AverageLengthEditField.Enable='off';
            drawnow;
            app.AverageNumberofGerminatedSporesEditField.Value='';
            app.AverageNumberofGerminatedSporesEditField.Enable='off';
            drawnow;
            app.CommentsTextArea.Value='';
            app.CommentsTextArea.Enable='off';
            drawnow;
            [app.filepath]=uigetdir('Select Folder');
            app.ImgDatastore=imageDatastore(app.filepath,'FileExtensions','.tif');
            app.SelectedFolderEditField.Value=app.filepath;
            app.GoButton.Enable='on';
            drawnow;          
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.stop=0;
            app.CommentsTextArea.Value='';
            app.CommentsTextArea.Enable='off';
            drawnow;
            app.OpenButton.Enable='off';
            drawnow;
            app.GoButton.Enable='off';
            drawnow;
            app.GoButton.Visible='off';
            drawnow;
            app.StopButton.Enable='on';
            drawnow;
            app.StopButton.Visible='on';
            drawnow;
            app.Label.Visible='on';
            drawnow;
            app.AverageLengthsButton.Enable='off';
            drawnow;
            app.NumberofGerminatedSporesButton.Enable='off';
            drawnow;
            app.Lengths=[];
            app.NumSpores=[];
            app.NumNotGerminatedSpores=[];   
            app.NotRemoved=[];
            app.NotRemovedLengths=[];
            app.NotGerminated=[];
            app.NotRemovedSpores=[];
            Comments='';
            CommentPlus='';
            Warning='';
            app.NumImages=numel(app.ImgDatastore.Files);
            app.Images=[];
            for i=1:numel(app.ImgDatastore.Files)
                if app.stop==1            
                    break
                end
                app.label=sprintf('Image %d/%d ...',i,app.NumImages);
                app.Label.Text=app.label;
                drawnow;
                SporesCenters=[];
                SporesRadii=[];
                Img=readimage(app.ImgDatastore,i);
                 if app.choose==1
                     labI=rgb2lab(Img);
                     ab=labI(:,:,1:2);
                     ab=im2single(ab);
                     nColors=2;
                     pixel_labels=imsegkmeans(ab,nColors,'NumAttempts',3);
                     Bin=imbinarize(pixel_labels);
                     Bin1=imbinarize(pixel_labels);
                     
                     [count,pixels]=imhist(Bin);
                     black=count(1);
                     white=count(2);
                     ratio=white/black;
                     
                     %different way to extract binary image: works better to remove edge-touching spores
                     
                     if ratio<0.1   %check if there are germinative tube
                         %delete touching-edge germinative tubes, but keep everithing if
                         %removing the germinative tubes that touch the edges implies
                         %removing too much;
                         Im=rgb2gray(Img);                         
                         Im=mat2gray(Im);
                         Im=imadjust(Im);
                         filtImage = stdfilt(Im, ones(25));
                         filtImage=imcomplement(filtImage);
                         filteredImage=imbinarize(filtImage,'adaptive','Sensitivity',0.7);
                         Bin_1=imcomplement(filteredImage);
                         
                         ConnComp_1=bwconncomp(Bin_1,4);
                         N_1=ConnComp_1.NumObjects;
                         stat_1=regionprops(ConnComp_1,'all');
                         Threshold=(30*white)/100;
                         for j=1:N_1
                             x=stat_1(j).PixelList(:,1);
                             y=stat_1(j).PixelList(:,2);
                             if stat_1(j).Area<=2000           %add the detected non germinated spores to list of 'Singletons'
                                 Bin(y,x)=0;                    %delete them from binary image --> only germinated spores left
                                 Bin1(y,x)=0;
                             end
                             if  stat_1(j).Extent>=0.7            %find the isolated (non germinated) spores (leftovers from previous equivalent operation)
                                 Bin(y,x)=0;
                                 Bin1(y,x)=0;
                             end
                         end
                         Cleaned=imclearborder(Bin_1,8);
                         Edges=Bin_1-Cleaned;
                         Edges=imfill(Edges,'holes');
                         Mask=logical(Edges);
                         Bin(Mask)=0;
                         Bin=imclearborder(Bin,8);
                         [CountP1,Pixel1]=imhist(Bin);
                         White1=CountP1(2);
                         if White1<Threshold                    %if removed too much than keep everything
                             Bin=Bin1;
                         end
                         
                         ConnComp=bwconncomp(Bin,4);
                         N=ConnComp.NumObjects;
                         stat=regionprops(ConnComp,'all');
                         
                         for j=1:N                            %for each detected connected component
                             if  stat(j).Area<=300            %find the isolated (non germinated) spores (leftovers from previous equivalent operation)
                                 x=stat(j).PixelList(:,1);
                                 y=stat(j).PixelList(:,2);
                                 Bin(y,x)=0;                  %remove leftovers
                             end
                         end
                         
                         [Centers,Radii]=imfindcircles(Bin,[11 20],'Sensitivity',0.9);     %look for circles(spores) in binary image in which non germinated spores have been removed
                         
                         [columns rows]=meshgrid(1:size(Bin,2),1:size(Bin,1));
                         for k=1:numel(Radii)
                             x=floor(Centers(k,1));
                             y=floor(Centers(k,2));
                             array2D = (rows- y).^2+ (columns - x).^2<(Radii(k)-5)^2;
                             if Bin(array2D)==1                       %if the circle contains only white pixels it means that it is really a spore
                                 SporesCenters=[SporesCenters;[x y]]; %save location of detected spore
                                 SporesRadii(end+1)=Radii(k);
                                 array2D = (rows- y).^2+ (columns - x).^2<(Radii(k))^2;
                                 Bin(array2D)=0;                      %hide pixels corresponding to spores (makes easier to compute length of tubes)
                             end
                         end
                         %repeat circles detection with different radius range: find more
                         %circles in this way (don't know why). Keep only one copy of any double
                         [Centers1,Radii1]=imfindcircles(Bin,[11 25],'Sensitivity',0.9);
                         for k=1:numel(Radii1)
                             xx=floor(Centers1(k,1));
                             yy=floor(Centers1(k,2));
                             array2D = (rows- yy).^2+ (columns - xx).^2<(Radii1(k)-5)^2;
                             if  isempty(SporesCenters)==0 & numel(find(SporesCenters==[xx yy]))==0 & Bin(array2D)==1
                                 SporesCenters=[SporesCenters;[xx yy]];
                                 SporesRadii(end+1)=Radii1(k);
                                 array2D = (rows- yy).^2+ (columns - xx).^2<(Radii1(k))^2;
                                 Bin(array2D)=0;
                             end
                         end
                         
                         app.NumSpores(end+1)=numel(SporesRadii);  %how many germinated spores have been detected
                         [CountPixels,PixelValue]=imhist(Bin);
                         AreaGerminativeTubes=CountPixels(2);
                         AverageTubeWidth=15;
                         TotalLength=AreaGerminativeTubes/AverageTubeWidth;
                         if White1<Threshold
                             %Comments=sprintf('Edge-touching tubes have not been removed.');
                             app.NotRemoved(end+1)=i;
                             app.NotRemovedLengths(end+1)=TotalLength/numel(SporesRadii);
                             app.NotRemovedSpores(end+1)=numel(SporesRadii);
                         end
                         app.Images(end+1)=i;
                         app.Lengths(end+1)=TotalLength/numel(SporesRadii);
                     else    
                         Im=rgb2gray(Img);
                         Im=mat2gray(Im);
                         Im=imadjust(Im);
                         [Centers,Radii]=imfindcircles(Im,[6 16],'Sensitivity',0.8,'EdgeThreshold',0.1,'ObjectPolarity','dark');
                         app.NumNotGerminatedSpores(end+1)=numel(Radii);
                         app.NotGerminated(end+1)=i;
                     end
                 elseif app.choose==2
                     Igray=rgb2gray(Img);
                     se=strel('disk',25);
                     background=imclose(Igray,se);
                     Im=background-Igray;
                     Im=imcomplement(Im);
                     Bin=imbinarize(Im);
                     Bin=imcomplement(Bin);
                     Bin1=Bin;
                     
                     IM=imadjust(Im);
                     IM=mat2gray(IM);
                     IM=imadjust(IM);
                     filteredImage = stdfilt(IM, ones(9));
                     filteredImage=imcomplement(filteredImage);
                     filteredImage=imbinarize(filteredImage,'adaptive','Sensitivity',0.6);
                     Bin_1=imcomplement(filteredImage);
                     
                     [count,pixels]=imhist(Bin_1);
                     black=count(1);
                     white=count(2);
                     ratio=white/black;
                     
                     if ratio<0.5
                         ConnComp1=bwconncomp(Bin_1,4);
                         N_1=ConnComp1.NumObjects;
                         stat_1=regionprops(ConnComp1,'all');
                         
                         [count,pixels]=imhist(Bin);
                         white=count(2);
                         Threshold=(30*white)/100;
                         for j=1:N_1
                             x=stat_1(j).PixelList(:,1);
                             y=stat_1(j).PixelList(:,2);
                             if stat_1(j).Area<=1000            %add the detected non germinated spores to list of 'Singletons'
                                 Bin(y,x)=0;                    %delete them from binary image --> only germinated spores left
                                 Bin1(y,x)=0;
                             end
                             if  stat_1(j).Extent>=0.7
                                 Bin(y,x)=0;
                                 Bin1(y,x)=0;
                             end
                         end
                         Cleaned=imclearborder(Bin_1,8);
                         Edges=Bin_1-Cleaned;
                         Edges=imfill(Edges,'holes');
                         Mask=logical(Edges);
                         Bin(Mask)=0;
                         Bin=imclearborder(Bin,8);
                         [CountP1,Pixel1]=imhist(Bin);
                         White1=CountP1(2);
                         if White1<Threshold                    %if removed too much than keep everything
                             Bin=Bin1;
                         end
                         
                         ConnComp=bwconncomp(Bin,4);
                         N=ConnComp.NumObjects;
                         stat=regionprops(ConnComp,'all');
                         
                         for j=1:N                            %for each detected connected component
                             if  stat(j).Area<=400            %find the isolated (non germinated) spores (leftovers from previous equivalent operation)
                                 x=stat(j).PixelList(:,1);
                                 y=stat(j).PixelList(:,2);
                                 Bin(y,x)=0;                  %remove leftovers
                             end
                         end
                         
                         ConnComp=bwconncomp(Bin,4);
                         N=ConnComp.NumObjects;
                         stat=regionprops(ConnComp,'all');
                         for j=1:N                            %for each detected connected component
                             if  stat(j).Extent>=0.7            %find the isolated (non germinated) spores (leftovers from previous equivalent operation)
                                 x=stat(j).PixelList(:,1);
                                 y=stat(j).PixelList(:,2);
                                 Bin(y,x)=0;
                             end
                         end
                         [Centers,Radii]=imfindcircles(Bin,[6 12],'Sensitivity',0.93,'EdgeThreshold',0.89,'ObjectPolarity','bright');  %look for circles(spores) in binary image in which non germinated spores have been removed
                         %imshow(Img,'Parent',app.UIAxes_1);
                         %hold(app.UIAxes_1,'on');
                         [columns rows]=meshgrid(1:size(Bin,2),1:size(Bin,1));
                         for k=1:numel(Radii)
                             x=floor(Centers(k,1));
                             y=floor(Centers(k,2));
                             array2D = (rows- y).^2+ (columns - x).^2<(Radii(k))^2;
                             Bin(array2D)=0;                      %hide pixels corresponding to spores (makes easier to compute length of tubes)
                             %x=Centers(k,1)-Radii(k);
                             %y=Centers(k,2)-Radii(k);
                             %pos=[x y Radii(k) Radii(k)];
                             %rectangle('Position',pos,'Curvature',[1 1],'Parent',app.UIAxes_1,'EdgeColor','r','LineWidth',2);
                         end
                         
                         app.NumSpores(end+1)=numel(Radii);  %how many germinated spores have been detected
                         [CountPixels,PixelValue]=imhist(Bin);
                         AreaGerminativeTubes=CountPixels(2);
                         AverageTubeWidth=5;
                         TotalLength=AreaGerminativeTubes/AverageTubeWidth;
                         if White1<Threshold
                             app.NotRemoved(end+1)=i;
                             app.NotRemovedLengths(end+1)=TotalLength/numel(Radii);
                             app.NotRemovedSpores(end+1)=numel(Radii);
                         end
                         app.Images(end+1)=i;
                         app.Lengths(end+1)=TotalLength/numel(Radii);
                     else
                         [Centers,Radii]=imfindcircles(Igray,[6 16],'Sensitivity',0.8,'EdgeThreshold',0.1,'ObjectPolarity','dark');
                         app.NumNotGerminatedSpores(end+1)=numel(Radii);
                         app.NotGerminated(end+1)=i;
                     end
                 end            
            end 
           
            if app.stop==1
                drawnow;
                app.AverageLengthEditField.Enable='off';
                app.AverageNumberofGerminatedSporesEditField.Enable='off';
                app.CommentsTextArea.Enable='on';
                app.CommentsTextArea.Value=sprintf('Process has been manually stopped.');
                cla(app.UIAxes);
                app.GoButton.Visible='on';
                drawnow;
                app.GoButton.Enable='on';
                drawnow;                
            else      
                app.AverageLengthsButton.Enable='on';
                drawnow;
                app.NumberofGerminatedSporesButton.Enable='on';
                drawnow;
                if length(app.NotGerminated)==numel(app.ImgDatastore.Files)
                    Comments='No germinated spores have been detected.';
                elseif length(app.NotGerminated)==0
                    app.AverageLengthEditField.Value=sprintf('%0.2f +/- %0.2f',sum(app.Lengths)/numel(app.ImgDatastore.Files), std(app.Lengths));
                    app.AverageNumberofGerminatedSporesEditField.Value=sprintf('%0.2f +/- %0.2f',sum(app.NumSpores)/numel(app.ImgDatastore.Files),std(app.NumSpores));
                else
                    app.NumImages=numel(app.ImgDatastore.Files)-length(app.NotGerminated);
                    app.AverageLengthEditField.Value=sprintf('%0.2f +/- %0.2f',sum(app.Lengths)/app.NumImages, std(app.Lengths));
                    app.AverageNumberofGerminatedSporesEditField.Value=sprintf('%0.2f +/- %0.2f',sum(app.NumSpores)/app.NumImages,std(app.NumSpores));
                    CommentPlus=sprintf('In %d images out of %d no germinated spores have been detected (red).',length(app.NotGerminated),numel(app.ImgDatastore.Files));
                end
                if numel(app.NotRemoved)>0
                    Comments=sprintf('Edge-touching tubes have not been always removed (black).');
                end
                
                app.AverageLengthEditField.Enable='on';
                app.AverageNumberofGerminatedSporesEditField.Enable='on';
                app.CommentsTextArea.Enable='on';
                app.CommentsTextArea.Value=sprintf('%s \n%s',Comments,CommentPlus);
            end          
            app.OpenButton.Enable='on';
            drawnow;
            app.StopButton.Enable='off';
            drawnow;
            app.StopButton.Visible='off';
            drawnow;
            app.GoButton.Visible='on';
            drawnow;
            app.Label.Visible='off';
            drawnow;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.ButtonGroup.SelectedObject; 
            X=cat(2,app.Images,app.NotGerminated);       
            if app.selectedButton==app.AverageLengthsButton       
                Y=cat(2,app.Lengths,zeros(1,length(app.NotGerminated)));
                [Xordered, firstsortorder] = sort(X);
                Yordered = Y(firstsortorder);
                app.UIAxes.YLabel.String='Average Lengths'; 
                UpYLim=1000;
                app.UIAxes.YLim=[0,UpYLim];
                app.UIAxes.YTick=0:200:UpYLim;
                app.UIAxes.YTickLabel=0:200:UpYLim;
                UpXLim=numel(app.ImgDatastore.Files)+0.2;
                app.UIAxes.XLim=[0.8,UpXLim];
                app.UIAxes.XTick=1:length(X);
                app.UIAxes.XTickLabel=1:length(X);
                if numel(app.ImgDatastore.Files)<20                    
                    b1=bar(app.UIAxes,Xordered,Yordered,0.1,'FaceColor','flat');
                    for j=1:length(app.NotRemoved)
                        b1.CData(app.NotRemoved(j),:)=[0 0 0];
                    end
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                    hold(app.UIAxes,'off');
                else
                    plot(app.UIAxes,Xordered,Yordered);
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.NotRemoved,app.NotRemovedLengths,'*','Color','k');
                    hold(app.UIAxes,'off');
                end
                if length(app.NotGerminated)==0
                    yline(app.UIAxes,sum(app.Lengths)/numel(app.ImgDatastore.Files),':','Average Length','LineWidth',2);
                else
                    yline(app.UIAxes,sum(app.Lengths)/app.NumImages,':','Average Length','LineWidth',1);
                end
            elseif app.selectedButton==app.NumberofGerminatedSporesButton
                app.UIAxes.YLabel.String='Number of Germinated Spores';
                Y=cat(2,app.NumSpores,zeros(1,length(app.NotGerminated)));
                [Xordered, firstsortorder] = sort(X);
                Yordered = Y(firstsortorder);
                if app.choose==1
                    UpYLim=200;
                elseif app.choose==2
                    UpYLim=500;
                end
                app.UIAxes.YLim=[0,UpYLim];
                app.UIAxes.YTick=0:50:UpYLim;
                app.UIAxes.YTickLabel=0:50:UpYLim;
                UpXLim=numel(app.ImgDatastore.Files)+0.2;
                app.UIAxes.XLim=[0.8,UpXLim];
                app.UIAxes.XTick=1:length(X);
                app.UIAxes.XTickLabel=1:length(X);
                if numel(app.ImgDatastore.Files)<20                    
                    b1=bar(app.UIAxes,Xordered,Yordered,0.1,'FaceColor','flat');
                    for j=1:length(app.NotRemoved)
                        b1.CData(app.NotRemoved(j),:)=[0 0 0];
                    end    
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                    hold(app.UIAxes,'off');
                else
                    plot(app.UIAxes,Xordered,Yordered);
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.NotRemoved,app.NotRemovedSpores,'*','Color','k');
                    hold(app.UIAxes,'off');
                end
                if length(app.NotGerminated)==0
                    yline(app.UIAxes,sum(app.NumSpores)/numel(app.ImgDatastore.Files),':','Average Length','LineWidth',2);
                else
                    yline(app.UIAxes,sum(app.NumSpores)/app.NumImages,':','Average Number of Germinated Spores','LineWidth',1);
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop=1;
            app.label=sprintf('Ending process...');
            app.StopButton.Enable='off';
            drawnow;
            app.StopButton.Visible='off';
            drawnow;
            app.GoButton.Visible='on';
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.UploadImagesLabel = uilabel(app.UIFigure);
            app.UploadImagesLabel.Text = 'Upload Images:';
            app.MagnitudeDropDownLabel = uilabel(app.UIFigure);
            app.MagnitudeDropDownLabel.Text = 'Magnitude';
            app.MagnitudeDropDown = uidropdown(app.UIFigure);
            app.MagnitudeDropDown.Items = {'Select...', 'X100', 'X40'};
            app.MagnitudeDropDown.ValueChangedFcn = createCallbackFcn(app, @MagnitudeDropDownValueChanged, true);
            app.MagnitudeDropDown.Value = 'Select...';
            app.SelectedFolderEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedFolderEditFieldLabel.Text = 'Selected Folder:';
            app.SelectedFolderEditField = uieditfield(app.UIFigure, 'text');
            app.SelectedFolderEditField.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.CommentsTextAreaLabel = uilabel(app.UIFigure);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.UIFigure);
            app.CommentsTextArea.Editable = 'off';
            app.CommentsTextArea.Enable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Analysed Image')
            ylabel(app.UIAxes, 'Average Lengths')
            app.UIAxes.XLim = [0.8 10.2];
            app.UIAxes.YLim = [0 1000];
            app.UIAxes.XTick = [1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes.YTick = [0 100 200 300 400 500 600 700 800 900 1000];
            app.AverageLengthEditFieldLabel = uilabel(app.UIFigure);
            app.AverageLengthEditFieldLabel.Text = 'Average Length';
            app.AverageLengthEditField = uieditfield(app.UIFigure, 'text');
            app.AverageLengthEditField.Editable = 'off';
            app.AverageLengthEditField.Enable = 'off';
            app.AverageNumberofGerminatedSporesEditFieldLabel = uilabel(app.UIFigure);
            app.AverageNumberofGerminatedSporesEditFieldLabel.Text = 'Average Number of Germinated Spores';
            app.AverageNumberofGerminatedSporesEditField = uieditfield(app.UIFigure, 'text');
            app.AverageNumberofGerminatedSporesEditField.Editable = 'off';
            app.AverageNumberofGerminatedSporesEditField.Enable = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.AverageLengthsButton = uiradiobutton(app.ButtonGroup);
            app.AverageLengthsButton.Enable = 'off';
            app.AverageLengthsButton.Text = 'Average Lengths';
            app.AverageLengthsButton.Value = true;
            app.NumberofGerminatedSporesButton = uiradiobutton(app.ButtonGroup);
            app.NumberofGerminatedSporesButton.Enable = 'off';
            app.NumberofGerminatedSporesButton.Text = 'Number of Germinated Spores';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppAverageLengthNew2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end