classdef AppAverageLengthNew3new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PrecisionBioUIFigure            matlab.ui.Figure
        UploadImagesPanel               matlab.ui.container.Panel
        MagnificationDropDownLabel      matlab.ui.control.Label
        MagnificationDropDown           matlab.ui.control.DropDown
        UploadButton                    matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        SelectedFolderLabel             matlab.ui.control.Label
        SelectedFolderEditField         matlab.ui.control.EditField
        GoButton                        matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        unithlengthpixelsLabel          matlab.ui.control.Label
        Conversion                      matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        ResultsPanel                    matlab.ui.container.Panel
        StandardDeviationLabel_2        matlab.ui.control.Label
        StandardDeviationLabel          matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        CommentsTextAreaLabel           matlab.ui.control.Label
        CommentsTextArea                matlab.ui.control.TextArea
        AverageLengthEditFieldLabel     matlab.ui.control.Label
        AverageLengthEditField          matlab.ui.control.EditField
        AverageNofGerminatedSporesEditFieldLabel  matlab.ui.control.Label
        AverageNofGerminatedSporesEditField  matlab.ui.control.EditField
        Panel                           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        ButtonGroup                     matlab.ui.container.ButtonGroup
        AverageLengthsButton            matlab.ui.control.RadioButton
        NumberofGerminatedSporesButton  matlab.ui.control.RadioButton
        namesoftwareLabel               matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        PanelScrollable                 matlab.ui.container.Panel
    end

    
    properties (Access = private)
        value
        path
        choose
        ImgDatastore
        selectedButton
        Comment
        GerminatedSpores
        AverageLength
        filepath
        NumImages
        Images
        NotRemoved
        NotGerminated
        Lengths
        NotRemovedLengths
        NumSpores
        NumNotRemovedSpores
        NumNotGerminatedSpores
        Spores
        stop
        label
        PixelLength
        PixelSTD
        AllLengths
        y
        Imshow
        Titles
        NumGerminatedSpores
        T
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MagnificationDropDown
        function MagnificationDropDownValueChanged(app, event)
            app.value = app.MagnificationDropDown.Value;
            
            if app.value=="X40"
                app.choose=2;
                app.Label_2.Visible='on';
                drawnow;
            elseif app.value=="X100"
                app.choose=1;
                app.Label_2.Visible='off';
                drawnow;
            end
            %enable upload button
            app.UploadButton.Visible='on';
            drawnow;
            app.UploadButton.Enable='on';
            drawnow;
            %unable stop button
            app.StopButton.Enable='off';
            drawnow;
            app.StopButton.Visible='off';
            drawnow;
            %go button not clickable yet
            app.GoButton.Visible='on';
            drawnow;
            app.GoButton.Enable='off';
            drawnow;
            %unable save button
            app.SaveButton.Enable='off';
            drawnow;
            %initialize comment area with blank section, ready to be filled
            %with new comments
            app.CommentsTextArea.Value='';
            app.CommentsTextArea.Enable='off';
            drawnow;
            %hide&clean result section
            app.AverageLengthsButton.Enable='off';
            drawnow;
            app.NumberofGerminatedSporesButton.Enable='off';
            drawnow;
            app.AverageLengthEditField.Value='';
            app.AverageLengthEditField.Enable='off';
            drawnow;
            app.AverageNofGerminatedSporesEditField.Value='';
            app.AverageNofGerminatedSporesEditField.Enable='off';
            drawnow;
            app.SelectedFolderEditField.Value='';
            cla(app.UIAxes);
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            cla(app.UIAxes);
            app.UIAxes.XTickLabel=1:10;
            xtickangle(app.UIAxes,0);
            drawnow;
            app.StopButton.Enable='off';
            drawnow;
            app.StopButton.Visible='off';
            drawnow;
            app.GoButton.Visible='on';
            drawnow;
            %wait till the end of computations before displaying results
            app.SaveButton.Enable='off';
            drawnow;
            app.AverageLengthsButton.Enable='off';
            drawnow;
            app.NumberofGerminatedSporesButton.Enable='off';
            drawnow;
            app.AverageLengthEditField.Value='';
            app.AverageLengthEditField.Enable='off';
            drawnow;
            app.AverageNofGerminatedSporesEditField.Value='';
            app.AverageNofGerminatedSporesEditField.Enable='off';
            drawnow;
            app.CommentsTextArea.Value='';
            app.CommentsTextArea.Enable='off';
            drawnow;
            %             file=fopen('settings.ini','r');
            %             Folder=fscanf(file,'%s');
            %open dialog box in current working directory. User must select
            %a folder containing only tif files.
            [Folder]=textread('settings.ini','%s');
            [app.filepath]=uigetdir(char(Folder),'Select Folder');
            drawnow;
            figure(app.PrecisionBioUIFigure)
            if ~ischar(app.filepath)
                return;
            end
            dir_struct = dir( fullfile(app.filepath,'*.tif') );
            if numel(dir_struct) == 0
                app.SelectedFolderEditField.Value='Folder not valid.';
                app.GoButton.Enable='off';
                drawnow;
                return;
            end
            
                      
            app.ImgDatastore=imageDatastore(app.filepath,'FileExtensions','.tif');
            %show selected folder. User can check and change it if needed
            app.SelectedFolderEditField.Value=app.filepath;
            app.Label.Text=sprintf('Image 0/%d',numel(app.ImgDatastore.Files));
            app.Label.Visible='on';
            drawnow;
            %ready to start computation
            app.GoButton.Enable='on';
            drawnow;
            app.MagnificationDropDown.Enable='on';
            drawnow;
            %enable section to change conversion parameter
            app.Conversion.Enable='on';
            drawnow;
            app.Conversion.Editable='on';
            drawnow;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            cla(app.UIAxes);
            %clear image space. Necessary when different folders analyzed
            %in sequence
            delete(app.Imshow);
            delete(app.Titles);
            app.stop=0;
            %unable parameter settings and all buttons except Stop during computation
            app.MagnificationDropDown.Enable='off';
            drawnow;
            app.UploadButton.Enable='off';
            drawnow;
            app.GoButton.Enable='off';
            drawnow;
            app.GoButton.Visible='off';
            drawnow;
            app.StopButton.Enable='on';
            drawnow;
            app.StopButton.Visible='on';
            drawnow;
            app.SaveButton.Enable='off';
            drawnow;
            app.Label.Visible='on';
            drawnow;
            app.AverageLengthEditField.Value='';
            app.AverageLengthEditField.Enable='off';
            drawnow;
            app.AverageNofGerminatedSporesEditField.Value='';
            app.AverageNofGerminatedSporesEditField.Enable='off';
            drawnow;
            app.CommentsTextArea.Value='';
            app.CommentsTextArea.Enable='off';
            drawnow;
            app.AverageLengthsButton.Enable='off';
            drawnow;
            app.NumberofGerminatedSporesButton.Enable='off';
            drawnow;
            app.Conversion.Editable='off';
            drawnow;
            app.ButtonGroup.SelectedObject=app.AverageLengthsButton;
            drawnow;
            %main script: initialization of variables
            app.Lengths=[];
            app.NotRemovedLengths=[];
            app.NumSpores=[];
            app.NumNotRemovedSpores=[];
            app.NumNotGerminatedSpores=[];
            app.NumGerminatedSpores=[];
            app.Images=[];
            app.NotRemoved=[];
            app.NotGerminated=[];
            Comments='';
            CommentPlus='';
            app.Imshow=[];
            app.Titles=[];
            app.T={};
            app.NumImages=numel(app.ImgDatastore.Files);
            NumColumns=3;
            NumRows=ceil(app.NumImages/NumColumns);
            %adapt window for different screen resolutions
            %             screenSize = get(groot,'ScreenSize');
            %             screenWidth = screenSize(3);
            %             screenHeight = screenSize(4);
            %             left = screenWidth*0.234375;
            bottom = 200;
            %             width = screenWidth*0.3098;
            height = 808;
            drawnow;
            app.PrecisionBioUIFigure.Position = [600 bottom 793 height];
            %             a1=screenWidth*0.1332;
            %             a2=screenHeight*0.1257;
            app.UploadImagesPanel.Position=[18,615,341,181];
            %             a1=screenWidth*0.1332;
            %             a2=screenHeight*0.1333;
            app.ResultsPanel.Position=[18,417,341,192];
            %             a1=screenWidth*0.1617;
            %             a2=screenHeight*0.26458;
            app.Panel.Position=[366,417,414,380];
            %             a1=screenWidth*0.2976;
            %             a2=screenHeight*0.34375;
            app.PanelScrollable.Position=[18,15,762,395];
            %             a1=screenWidth*0.1332;
            %             a2=screenHeight*0.1257;
            app.Panel_2.Parent=app.PanelScrollable;
            %             a1=screenWidth*0.2976;
            height=295*NumRows;
            app.Panel_2.Position=[0,0,762,height];
            %             height=screenHeight*0.2048*NumRows;
            %             app.Panel_2.Position=[0,15,a1,height];
            app.PanelScrollable.Scrollable='on';
            drawnow;
            tic;
            for i=1:numel(app.ImgDatastore.Files)
                %if Stop button is clicked, end the computation
                if app.stop==1
                    break
                end
                %show which image is being analyzed in this moment
                app.label=sprintf('Image %d/%d',i,app.NumImages);
                app.Label.Text=app.label;
                drawnow;
                SporesCenters=[];
                SporesRadii=[];
                SporesCentersUsed=[];
                SporesRadiiUsed=[];
                [Img,info]=readimage(app.ImgDatastore,i);
                [filepath,name,ext] = fileparts(info.Filename);
                %if magnification = X100
                if app.choose==1
                    labI=rgb2lab(Img);
                    ab=labI(:,:,1:2);
                    ab=im2single(ab);
                    nColors=2;
                    pixel_labels=imsegkmeans(ab,nColors,'NumAttempts',3);
                    BinaryImg1=imbinarize(pixel_labels);
                    Bin1=imbinarize(pixel_labels);
                    
                    [count,pixels]=imhist(BinaryImg1);
                    black=count(1);
                    white=count(2);
                    ratio=white/black;
                    
                    %check if binary image has white tubes and black
                    %background. If not (ratio>10) take its complement
                    if ratio>10
                        BinaryImg1=imcomplement(BinaryImg1);
                        Bin1=BinaryImg1;
                        [count,pixels]=imhist(BinaryImg1);
                        black=count(1);
                        white=count(2);
                        ratio=white/black;
                    end
                    
                    %check if binary image has defined objects (not
                    %sal&pepper pattern). If yes then:
                    if ratio<0.1
                        Im=rgb2gray(Img);
                        Im=mat2gray(Im);
                        Im=imadjust(Im);
                        %create binary image in which the tubes are not
                        %splitted
                        filtImage = stdfilt(Im, ones(25));
                        filtImage=imcomplement(filtImage);
                        filteredImage=imbinarize(filtImage,'adaptive','Sensitivity',0.7);
                        BinaryImg2=imcomplement(filteredImage);
                        %delete edge touching elements, if this doesn't
                        %mean deleting too much
                        Threshold=(30*white)/100;
                        Cleaned=imclearborder(BinaryImg2,8);
                        Edges=BinaryImg2-Cleaned;
                        Edges=imfill(Edges,'holes');
                        Mask=logical(Edges);
                        BinaryImg1(Mask)=0;
                        BinaryImg1=imclearborder(BinaryImg1,8);
                        [CountP1,Pixel1]=imhist(BinaryImg1);
                        White1=CountP1(2);
                        %if too may elements were deleted, restore initial
                        %binary version
                        if White1<Threshold
                            BinaryImg1=Bin1;
                        end
                        %remove leftovers from noisy background or not
                        %germinated spores
                        ConnComp_1=bwconncomp(BinaryImg2,4);
                        N_1=ConnComp_1.NumObjects;
                        stat_1=regionprops(ConnComp_1,'all');
                        for j=1:N_1
                            x=stat_1(j).PixelList(:,1);
                            y=stat_1(j).PixelList(:,2);
                            
                            if stat_1(j).Area<=2000
                                BinaryImg1(y,x)=0;
                                Bin1(y,x)=0;
                            end
                            
                            if  stat_1(j).Extent>=0.7
                                BinaryImg1(y,x)=0;
                                Bin1(y,x)=0;
                            end
                            
                        end
                        
                        ConnComp=bwconncomp(BinaryImg1,4);
                        N=ConnComp.NumObjects;
                        stat=regionprops(ConnComp,'all');
                        for j=1:N
                            if  stat(j).Area<=300
                                x=stat(j).PixelList(:,1);
                                y=stat(j).PixelList(:,2);
                                BinaryImg1(y,x)=0;
                                Bin1(y,x)=0;
                            end
                        end
                        %detect all germinated spores (this is performed on the binary image in which edge touching
                        %element were not removed since the total number of germinated spores is more relevant)
                        [Centers,Radii]=imfindcircles(Bin1,[11 20],'Sensitivity',0.9);
                        [columns rows]=meshgrid(1:size(Bin1,2),1:size(Bin1,1));
                        for k=1:numel(Radii)
                            x=floor(Centers(k,1));
                            y=floor(Centers(k,2));
                            array2D = (rows- y).^2+ (columns - x).^2<(Radii(k)-5)^2;
                            
                            if Bin1(array2D)==1
                                SporesCenters=[SporesCenters;[x y]];
                                SporesRadii(end+1)=Radii(k);
                                array2D = (rows- y).^2+ (columns - x).^2<(Radii(k))^2;
                                Bin1(array2D)=0;
                                %Img(array2D,2)=256;
                            end
                            
                        end
                        [Centers1,Radii1]=imfindcircles(Bin1,[14 25],'Sensitivity',0.9);
                        for k=1:numel(Radii1)
                            xx=floor(Centers1(k,1));
                            yy=floor(Centers1(k,2));
                            array2D = (rows- yy).^2+ (columns - xx).^2<(Radii1(k)-5)^2;
                            
                            if  isempty(SporesCenters)==0 & numel(find(SporesCenters==[xx yy]))==0 & BinaryImg1(array2D)==1
                                SporesCenters=[SporesCenters;[xx yy]];
                                SporesRadii(end+1)=Radii1(k);
                                array2D = (rows- yy).^2+ (columns - xx).^2<(Radii1(k))^2;
                                Bin1(array2D)=0;
                                %Img(array2D,2)=256;
                            end
                            
                        end
                        app.NumGerminatedSpores(end+1)=numel(SporesRadii);
                        
                        %look for spores using binary image where edge touching elements were removed. Necessary to
                        %compute average length
                        [Centers,Radii]=imfindcircles(BinaryImg1,[11 20],'Sensitivity',0.9);
                        [columns rows]=meshgrid(1:size(BinaryImg1,2),1:size(BinaryImg1,1));
                        for k=1:numel(Radii)
                            x=floor(Centers(k,1));
                            y=floor(Centers(k,2));
                            array2D = (rows- y).^2+ (columns - x).^2<(Radii(k)-5)^2;
                            
                            if BinaryImg1(array2D)==1
                                SporesCentersUsed=[SporesCentersUsed;[x y]];
                                SporesRadiiUsed(end+1)=Radii(k);
                                array2D = (rows- y).^2+ (columns - x).^2<(Radii(k))^2;
                                BinaryImg1(array2D)=0;
                                Img(array2D)=256;
                            end
                            
                        end
                        [Centers1,Radii1]=imfindcircles(BinaryImg1,[14 25],'Sensitivity',0.9);
                        for k=1:numel(Radii1)
                            xx=floor(Centers1(k,1));
                            yy=floor(Centers1(k,2));
                            array2D = (rows- yy).^2+ (columns - xx).^2<(Radii1(k)-5)^2;
                            %check if detected spore is actually a spore
                            %(the inner part of the circle must be all white)
                            if  isempty(SporesCentersUsed)==0 & numel(find(SporesCentersUsed==[xx yy]))==0 & BinaryImg1(array2D)==1
                                SporesCentersUsed=[SporesCentersUsed;[xx yy]];
                                SporesRadiiUsed(end+1)=Radii1(k);
                                array2D = (rows- yy).^2+ (columns - xx).^2<(Radii1(k))^2;
                                BinaryImg1(array2D)=0;
                                Img(array2D)=256;
                            end
                            
                        end
                        %color elements used in the computation to give a
                        %visual feeling of what happened
                        Img(BinaryImg1)=256;
                        %compute average length
                        [CountPixels,PixelValue]=imhist(BinaryImg1);
                        AreaGerminativeTubes=CountPixels(2);
                        AverageTubeWidth=15;
                        TotalLength=AreaGerminativeTubes/AverageTubeWidth;
                        
                        if White1<Threshold
                            app.NotRemoved(end+1)=i;
                            app.NotRemovedLengths(end+1)=TotalLength/numel(SporesRadiiUsed);
                            app.NumNotRemovedSpores(end+1)=numel(SporesRadiiUsed);
                        else
                            app.Images(end+1)=i;
                            app.Lengths(end+1)=TotalLength/numel(SporesRadiiUsed);
                            app.NumSpores(end+1)=numel(SporesRadiiUsed);
                        end
                        
                        Title=sprintf('%s',name);%\nN.Spores:%d Av.Length:%0.2f,numel(SporesRadii),TotalLength/(numel(SporesRadii)*app.Conversion.Value)
                    else    %if a nice binary form couldn't be computed for the picture most of the times is because in the image there are
                        %germinated spores, but only non
                        %germinated spores
                        Im=rgb2gray(Img);
                        Im=mat2gray(Im);
                        Im=imadjust(Im);
                        [columns rows]=meshgrid(1:size(BinaryImg1,2),1:size(BinaryImg1,1));
                        %look for spores in the black and white image
                        [Centers,Radii]=imfindcircles(Im,[6 16],'Sensitivity',0.8,'EdgeThreshold',0.1,'ObjectPolarity','dark');
                        for k=1:numel(Radii)
                            xx=floor(Centers(k,1));
                            yy=floor(Centers(k,2));
                            array2D = (rows- yy).^2+ (columns - xx).^2<(Radii(k))^2;
                            Img(array2D)=256;
                        end
                        app.NumNotGerminatedSpores(end+1)=numel(Radii);
                        app.NotGerminated(end+1)=i;
                        Title=sprintf('%s',name);%\nN.Spores:%d Av.Length:%0.2f,numel(Radii),0
                    end
                    app.T{end+1}=Title;
                    %show analyzed images
                    splot=subplot(NumRows,NumColumns,i,'Parent',app.Panel_2);
                    splot.Position = splot.Position + [-0.05 0 0.035 0.055];
                    app.Imshow(end+1)=imshow(Img,'Parent',splot);
                    app.Titles(end+1)=title(Title,'FontWeight','normal','Parent',splot);
                    
                elseif app.choose==2 %if magnification = X40
                    Igray=rgb2gray(Img);
                    se=strel('disk',25);
                    background=imclose(Igray,se);
                    Im=background-Igray;
                    Im=imcomplement(Im);
                    BinaryImg1=imbinarize(Im);
                    BinaryImg1=imcomplement(BinaryImg1);
                    Bin1=BinaryImg1;
                    
                    IM=imadjust(Im);
                    IM=mat2gray(IM);
                    IM=imadjust(IM);
                    filteredImage = stdfilt(IM, ones(9));
                    filteredImage=imcomplement(filteredImage);
                    filteredImage=imbinarize(filteredImage,'adaptive','Sensitivity',0.6);
                    BinaryImg2=imcomplement(filteredImage);
                    
                    [count,pixels]=imhist(BinaryImg2);
                    black=count(1);
                    white=count(2);
                    ratio=white/black;
                    
                    if ratio>50
                        BinaryImg2=imcomplement(BinaryImg2);
                        [count,pixels]=imhist(BinaryImg2);
                        black=count(1);
                        white=count(2);
                        ratio=white/black;
                    end
                    
                    if ratio<0.5
                        [count,pixels]=imhist(BinaryImg1);
                        white=count(2);
                        Threshold=(30*white)/100;
                        Cleaned=imclearborder(BinaryImg2,8);
                        Edges=BinaryImg2-Cleaned;
                        Edges=imfill(Edges,'holes');
                        Mask=logical(Edges);
                        BinaryImg1(Mask)=0;
                        BinaryImg1=imclearborder(BinaryImg1,8);
                        [CountP1,Pixel1]=imhist(BinaryImg1);
                        White1=CountP1(2);
                        
                        if White1<Threshold
                            BinaryImg1=Bin1;
                        end
                        
                        ConnComp1=bwconncomp(BinaryImg2,4);
                        N_1=ConnComp1.NumObjects;
                        stat_1=regionprops(ConnComp1,'all');
                        for j=1:N_1
                            x=stat_1(j).PixelList(:,1);
                            y=stat_1(j).PixelList(:,2);
                            
                            if stat_1(j).Area<=1000
                                BinaryImg1(y,x)=0;
                                Bin1(y,x)=0;
                            end
                            
                            if  stat_1(j).Extent>=0.7
                                BinaryImg1(y,x)=0;
                                Bin1(y,x)=0;
                            end
                        end
                        
                        ConnComp=bwconncomp(BinaryImg1,4);
                        N=ConnComp.NumObjects;
                        stat=regionprops(ConnComp,'all');
                        for j=1:N
                            if  stat(j).Area<=400
                                x=stat(j).PixelList(:,1);
                                y=stat(j).PixelList(:,2);
                                BinaryImg1(y,x)=0;
                                Bin1(y,x)=0;
                            end
                        end
                        
                        ConnComp=bwconncomp(BinaryImg1,4);
                        N=ConnComp.NumObjects;
                        stat=regionprops(ConnComp,'all');
                        for j=1:N
                            if  stat(j).Extent>=0.7
                                x=stat(j).PixelList(:,1);
                                y=stat(j).PixelList(:,2);
                                BinaryImg1(y,x)=0;
                                Bin1(y,x)=0;
                            end
                        end
                        
                        [Centers,Radii]=imfindcircles(Bin1,[6 12],'Sensitivity',0.93,'EdgeThreshold',0.89,'ObjectPolarity','bright');  %look for circles(spores) in binary image in which non germinated spores have been removed
                        app.NumGerminatedSpores(end+1)=numel(Radii);
                        
                        [Centers,Radii]=imfindcircles(BinaryImg1,[6 12],'Sensitivity',0.93,'EdgeThreshold',0.89,'ObjectPolarity','bright');  %look for circles(spores) in binary image in which non germinated spores have been removed
                        [columns rows]=meshgrid(1:size(BinaryImg1,2),1:size(BinaryImg1,1));
                        for k=1:numel(Radii)
                            x=floor(Centers(k,1));
                            y=floor(Centers(k,2));
                            array2D = (rows- y).^2+ (columns - x).^2<(Radii(k))^2;
                            BinaryImg1(array2D)=0;
                            Img(array2D)=256;
                        end
                        Img(BinaryImg1)=256;
                        [CountPixels,PixelValue]=imhist(BinaryImg1);
                        AreaGerminativeTubes=CountPixels(2);
                        AverageTubeWidth=5;
                        TotalLength=AreaGerminativeTubes/AverageTubeWidth;
                        
                        if White1<Threshold
                            app.NotRemoved(end+1)=i;
                            app.NotRemovedLengths(end+1)=TotalLength/numel(Radii);
                            app.NumNotRemovedSpores(end+1)=numel(Radii);
                        else
                            app.Images(end+1)=i;
                            app.Lengths(end+1)=TotalLength/numel(Radii);
                            app.NumSpores(end+1)=numel(Radii);
                        end
                        
                        Title=sprintf('%s',name);%\nN.Spores:%d Av.Length:%0.2f,numel(Radii),TotalLength/(numel(Radii)*app.Conversion.Value)
                    else
                        [columns rows]=meshgrid(1:size(BinaryImg1,2),1:size(BinaryImg1,1));
                        [Centers,Radii]=imfindcircles(Igray,[6 16],'Sensitivity',0.8,'EdgeThreshold',0.1,'ObjectPolarity','dark');
                        for k=1:numel(Radii)
                            x=floor(Centers(k,1));
                            y=floor(Centers(k,2));
                            array2D = (rows- y).^2+ (columns - x).^2<(Radii(k))^2;
                            Img(array2D)=256;
                        end
                        app.NumNotGerminatedSpores(end+1)=numel(Radii);
                        app.NotGerminated(end+1)=i;
                        Title=sprintf('%s',name);%\nN.Spores:%d Av.Length:%0.2f,numel(Radii),0
                    end
                    
                    app.T{end+1}=Title;
                    splot=subplot(NumRows,NumColumns,i,'Parent',app.Panel_2);
                    splot.Position = splot.Position + [-0.05 0 0.035 0.055];
                    app.Imshow=imshow(Img,'Parent',splot);
                    app.Titles=title(Title,'FontWeight','normal','Parent',splot);
                end
                
            end
            toc;
            %if stop button is clicked, show a message saying that the
            %process has been stopped manually and reactivate other buttons
            %and parametr setting sections
            if app.stop==1
                drawnow;
                app.AverageLengthEditField.Enable='off';
                app.AverageNofGerminatedSporesEditField.Enable='off';
                app.CommentsTextArea.Enable='on';
                app.CommentsTextArea.Value=sprintf('Process has been stopped manually.');
                cla(app.UIAxes);
                app.GoButton.Visible='on';
                drawnow;
                app.GoButton.Enable='on';
                drawnow;
                app.Conversion.Editable='on';
                drawnow;
            else %if the process was not stopped
                app.AverageLengthsButton.Enable='on';
                drawnow;
                app.NumberofGerminatedSporesButton.Enable='on';
                drawnow;
                %put together results of all the images in the folder
                app.Spores=cat(2,app.NumSpores,app.NumNotRemovedSpores);
                app.AllLengths=cat(2,app.Lengths,app.NotRemovedLengths);
                
                if length(app.NotGerminated)==numel(app.ImgDatastore.Files)  %if all images don't have germinated spores
                    Comments='No germinated spores have been detected.\n';
                elseif length(app.NotGerminated)==0  %if all images have germinated spores
                    if numel(app.NotRemoved)==numel(app.ImgDatastore.Files) %if in all images the edge touching elements were not removed
                        app.PixelLength=sum(app.NotRemovedLengths)/numel(app.NotRemovedLengths);
                        app.PixelSTD=std(app.NotRemovedLengths);
                    else %if in some images the edge touching elements were removed, for the final average length only consider
                        %images were edge touching el. where removed
                        app.PixelLength=sum(app.Lengths)/numel(app.Lengths);
                        app.PixelSTD=std(app.Lengths);
                    end
                    if numel(app.ImgDatastore.Files)==1 %special section if only one image in the folder
                        app.AverageLengthEditField.Value=sprintf('%0.2f',app.PixelLength/app.Conversion.Value);
                        app.AverageNofGerminatedSporesEditField.Value=sprintf('%0.2f',sum(app.NumGerminatedSpores)/numel(app.ImgDatastore.Files));
                    else
                        app.AverageLengthEditField.Value=sprintf('%0.2f +/- %0.2f',app.PixelLength/app.Conversion.Value,app.PixelSTD/app.Conversion.Value);
                        app.AverageNofGerminatedSporesEditField.Value=sprintf('%0.2f +/- %0.2f',sum(app.NumGerminatedSpores)/numel(app.ImgDatastore.Files),std(app.NumGerminatedSpores));
                    end
                else %if some images have germinated spores and some others don't
                    app.NumImages=numel(app.ImgDatastore.Files)-length(app.NotGerminated);
                    if numel(app.NotRemoved)==numel(app.ImgDatastore.Files)
                        app.PixelLength=sum(app.NotRemovedLengths)/numel(app.NotRemovedLengths);
                        app.PixelSTD=std(app.NotRemovedLengths);
                    else
                        app.PixelLength=sum(app.Lengths)/numel(app.Lengths);
                        app.PixelSTD=std(app.Lengths);
                    end
                    if numel(app.ImgDatastore.Files)==1
                        app.AverageLengthEditField.Value=sprintf('%0.2f',app.PixelLength/app.Conversion.Value);
                        app.AverageNofGerminatedSporesEditField.Value=sprintf('%0.2f',sum(app.NumGerminatedSpores)/app.NumImages);
                    else
                        app.AverageLengthEditField.Value=sprintf('%0.2f +/- %0.2f',app.PixelLength/app.Conversion.Value,app.PixelSTD/app.Conversion.Value);
                        app.AverageNofGerminatedSporesEditField.Value=sprintf('%0.2f +/- %0.2f',sum(app.NumGerminatedSpores)/app.NumImages,std(app.NumGerminatedSpores));
                    end
                    CommentPlus=sprintf('In %d images out of %d no germinated spores have been detected (red).',length(app.NotGerminated),numel(app.ImgDatastore.Files));
                end
                
                if numel(app.NotRemoved)>0
                    Comments=sprintf('Edge-touching elements have not been always removed (green).');
                end
                %show results
                app.AverageLengthEditField.Enable='on';
                app.AverageNofGerminatedSporesEditField.Enable='on';
                app.CommentsTextArea.Enable='on';
                app.CommentsTextArea.Value=sprintf('%s %s',Comments,CommentPlus);
                app.SaveButton.Enable='on';
                drawnow;
                %display results in graph on the right hand side: order lists such that you have right corespondence between image
                %and measures; rescale axes of graph; show bars if less than 20 images were analyzed and a trend line if more than
                %20 imgs were analyzed.
                X=cat(2,app.Images,app.NotRemoved,app.NotGerminated);
                app.y=cat(2,app.Lengths,app.NotRemovedLengths,zeros(1,length(app.NotGerminated)));
                Y=app.y./app.Conversion.Value;
                [Xordered, firstsortorder] = sort(X);
                YorderedLength = Y(firstsortorder);
                app.UIAxes.YLabel.String='Average Lengths';
                UpYLim=ceil(max(Y)/100)*100;
                app.UIAxes.YLim=[0,UpYLim];
                UpXLim=numel(app.ImgDatastore.Files)+0.2;
                app.UIAxes.XLim=[0.8,UpXLim];
                app.UIAxes.XTick=1:length(X);
                app.UIAxes.XTickLabel=app.T;
                xtickangle(app.UIAxes,45);
                if numel(app.ImgDatastore.Files)<20
                    b1=bar(app.UIAxes,Xordered,YorderedLength,0.1,'FaceColor','flat');
                    for j=1:length(app.NotRemoved)
                        b1.CData(app.NotRemoved(j),:)=[0.4660 0.6740 0.1880];
                    end
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                    hold(app.UIAxes,'off');
                else
                    plot(app.UIAxes,Xordered,YorderedLength);
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.NotRemoved,app.NotRemovedLengths/app.Conversion.Value,'*','Color',[0.4660 0.6740 0.1880]);
                    hold(app.UIAxes,'off');
                end
                %if length(app.NotGerminated)==0
                yline(app.UIAxes,app.PixelLength/app.Conversion.Value,':','Average Length','LineWidth',1);
                % else
                %    yline(app.UIAxes,sum(app.Lengths)/(app.NumImages*app.Conversion.Value),':','Average Length','LineWidth',1);
                %end
                
            end
            
            app.Conversion.Editable='on';
            drawnow;
            app.UploadButton.Enable='on';
            drawnow;
            app.StopButton.Enable='off';
            drawnow;
            app.StopButton.Visible='off';
            drawnow;
            app.GoButton.Visible='on';
            drawnow;
            app.GoButton.Enable='on';
            drawnow;
            app.Label.Text='';
            app.Label.Visible='off';
            drawnow;
            app.MagnificationDropDown.Enable='on';
            drawnow;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.ButtonGroup.SelectedObject;
            cla(app.UIAxes);
            
            if app.selectedButton==app.AverageLengthsButton
                X=cat(2,app.Images,app.NotRemoved,app.NotGerminated);
                Y=cat(2,app.Lengths/app.Conversion.Value,app.NotRemovedLengths/app.Conversion.Value,zeros(1,length(app.NotGerminated)));
                [Xordered, firstsortorder] = sort(X);
                YorderedLength = Y(firstsortorder);
                app.UIAxes.YLabel.String='Average Lengths';
                UpYLim=ceil(max(Y)/100)*100;
                app.UIAxes.YLim=[0,UpYLim];
                %                 app.UIAxes.YTick=0:ceil(UpYLim/4):UpYLim;
                %                 app.UIAxes.YTickLabel=0:ceil(UpYLim/4):UpYLim;
                UpXLim=numel(app.ImgDatastore.Files)+0.2;
                app.UIAxes.XLim=[0.8,UpXLim];
                app.UIAxes.XTick=1:length(X);
                app.UIAxes.XTickLabel=app.T;
                xtickangle(app.UIAxes,45);
                
                if numel(app.ImgDatastore.Files)<20
                    b1=bar(app.UIAxes,Xordered,YorderedLength,0.1,'FaceColor','flat');
                    for j=1:length(app.NotRemoved)
                        b1.CData(app.NotRemoved(j),:)=[0.4660 0.6740 0.1880];
                    end
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                    hold(app.UIAxes,'off');
                else
                    plot(app.UIAxes,Xordered,YorderedLength);
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.NotRemoved,app.NotRemovedLengths/app.Conversion.Value,'*','Color',[0.4660 0.6740 0.1880]);
                    hold(app.UIAxes,'off');
                end
                
                %if length(app.NotGerminated)==0
                yline(app.UIAxes,app.PixelLength/app.Conversion.Value,':','Average Length','LineWidth',1);
                %else
                %    yline(app.UIAxes,sum(app.Lengths)/(app.NumImages*app.Conversion.Value),':','Average Length','LineWidth',1);
                %end
                
            elseif app.selectedButton==app.NumberofGerminatedSporesButton
                app.UIAxes.YLabel.String='Number of Germinated Spores';
                Germ=cat(2,app.Images,app.NotRemoved);
                GermOrdered=sort(Germ);
                X=cat(2,GermOrdered,app.NotGerminated);
                Z=cat(2,app.NumGerminatedSpores,zeros(1,length(app.NotGerminated)));
                [Xordered, firstsortorder] = sort(X);
                ZorderedSpores = Z(firstsortorder);
                
                if app.choose==1
                    UpZLim=ceil(max(Z)/10)*10;
                    app.UIAxes.YLim=[0,UpZLim];
                elseif app.choose==2
                    UpZLim=ceil(max(Z)/100)*100;
                    app.UIAxes.YLim=[0,UpZLim];
                end
                
                UpXLim=numel(app.ImgDatastore.Files)+0.2;
                app.UIAxes.XLim=[0.8,UpXLim];
                app.UIAxes.XTick=1:length(X);
                app.UIAxes.XTickLabel=app.T;
                xtickangle(app.UIAxes,45);
                
                if numel(app.ImgDatastore.Files)<20
                    b1=bar(app.UIAxes,Xordered,ZorderedSpores,0.1);%,'FaceColor','flat');
                    %                     for j=1:length(app.NotRemoved)
                    %                         b1.CData(app.NotRemoved(j),:)=[0.4660 0.6740 0.1880];
                    %                     end
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                    hold(app.UIAxes,'off');
                else
                    plot(app.UIAxes,Xordered,ZorderedSpores);
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                    %                     hold(app.UIAxes,'on');
                    %                     plot(app.UIAxes,app.NotRemoved,app.NumNotRemovedSpores,'*','Color',[0.4660 0.6740 0.1880]);
                    hold(app.UIAxes,'off');
                end
                
                if length(app.NotGerminated)==0
                    yline(app.UIAxes,sum(app.NumGerminatedSpores)/numel(app.ImgDatastore.Files),':','Average Number of Germinated Spores','LineWidth',1);
                else
                    yline(app.UIAxes,sum(app.NumGerminatedSpores)/app.NumImages,':','Average Number of Germinated Spores','LineWidth',1);
                end
                
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButton.Enable='off';
            drawnow;
            app.stop=1;
            app.Label.Text='Ending process...';
            drawnow;
            app.StopButton.Visible='off';
            drawnow;
            app.GoButton.Visible='on';
            drawnow;
            app.MagnificationDropDown.Enable='on';
            drawnow;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            X=cat(2,app.Images,app.NotRemoved,app.NotGerminated);
            Y=cat(2,app.Lengths/app.Conversion.Value,app.NotRemovedLengths/app.Conversion.Value,zeros(1,length(app.NotGerminated)));
            Z=cat(2,app.NumSpores,app.NumNotRemovedSpores,zeros(1,length(app.NotGerminated)));
            [Xordered, firstsortorder] = sort(X);
            YorderedLength = Y(firstsortorder);
            
            Germ=cat(2,app.Images,app.NotRemoved);
            GermOrdered=sort(Germ);
            Xgerm=cat(2,GermOrdered,app.NotGerminated);
            W=cat(2,app.NumGerminatedSpores,zeros(1,length(app.NotGerminated)));
            
            target=sprintf('%s/*.*',app.filepath);
            [file,path]=uiputfile(target,'Save');
            [filepath,name,ext] = fileparts(file);
            if ~ischar(file); return; end
            imagename=sprintf('%s.jpg',name);
            fullname=fullfile(path,imagename);
            excelname=sprintf('%s.xlsx',name);
            fullnamexcel=fullfile(path,excelname);
            delete(fullname);
            delete(fullnamexcel);
            h = figure;
            set(gcf,'Position',[0 0 1750 1312]);
            ax=axes('Parent',h,'Position',[.05 .20 .4 .5]);
            ax2=axes('Parent',h,'Position',[.55 .20 .4 .5]);
            h.Visible = 'off';
            ax.YLabel.String='Average Lengths';
            UpYLim=ceil(max(app.y)/100)*100;
            ax.YLim=[0,UpYLim];
            ax.YTick=0:ceil(UpYLim/4):UpYLim;
            ax.YTickLabel=0:ceil(UpYLim/4):UpYLim;
            UpXLim=numel(app.ImgDatastore.Files)+0.2;
            ax.XLim=[0.9,UpXLim];
            ax.XTick=1:length(X);
            
            if numel(app.ImgDatastore.Files)<20
                b1=bar(ax,Xordered,YorderedLength,0.1,'FaceColor','flat');
                for j=1:length(app.NotRemoved)
                    b1.CData(app.NotRemoved(j),:)=[0.4660 0.6740 0.1880];
                end
                hold(ax,'on');
                p1=plot(ax,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                hold(ax,'on');
                p2=plot(nan,nan,'Color',[0.4660 0.6740 0.1880]);
                hold(ax,'off');
            else
                plot(ax,Xordered,YorderedLength);
                hold(ax,'on');
                p1=plot(ax,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                hold(ax,'on');
                p2=plot(ax,app.NotRemoved,app.NotRemovedLengths/app.Conversion.Value,'*','Color',[0.4660 0.6740 0.1880]);
                hold(ax,'off');
            end
            
            %if length(app.NotGerminated)==0
            yline(ax,app.PixelLength/app.Conversion.Value,':','Average Length','LineWidth',1);
            %legend(ax,p2,'Edge touching tubes not removed');
            %else
            %   yline(ax,sum(app.Lengths)/(app.NumImages*app.Conversion.Value),':','Average Length','LineWidth',1);
            %legend(ax,[p2 p1],'Edge touching tubes not removed','No germinated spores detected');
            %end
            ax.XTickLabel=app.T;
            xtickangle(ax,45);
            
            title(ax,'Average Lengths');
            
            ax2.YLabel.String='Number of Germinated Spores';
            [XgermOrdered, firstsortorder] = sort(Xgerm);
            WorderedSpores = W(firstsortorder);
            
            if app.choose==1
                UpZLim=ceil(max(Z)/10)*10;
                ax2.YLim=[0,UpZLim];
                ax2.YTick=0:ceil(UpZLim/4):UpZLim;
                ax2.YTickLabel=0:ceil(UpZLim/4):UpZLim;
            elseif app.choose==2
                UpZLim=ceil(max(Z)/100)*100;
                ax2.YLim=[0,UpZLim];
                ax2.YTick=0:ceil(UpZLim/40):UpZLim;
                ax2.YTickLabel=0:ceil(UpZLim/40):UpZLim;
            end
            
            UpXLim=numel(app.ImgDatastore.Files)+0.2;
            ax2.XLim=[0.9,UpXLim];
            ax2.XTick=1:length(X);
            
            if numel(app.ImgDatastore.Files)<20
                b1=bar(ax2,XgermOrdered,WorderedSpores,0.1,'FaceColor','flat');
                %                 for j=1:length(app.NotRemoved)
                %                     b1.CData(app.NotRemoved(j),:)=[0.4660 0.6740 0.1880];
                %                 end
                hold(ax2,'on');
                p1=plot(ax2,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                hold(ax2,'on');
                p2=plot(nan,nan,'Color',[0.4660 0.6740 0.1880]);
                hold(ax2,'off');
            else
                plot(ax2,XgermOrdered,WorderedSpores);
                hold(ax2,'on');
                p1=plot(ax2,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                hold(ax2,'on');
                p2=plot(nan,nan,'Color',[0.4660 0.6740 0.1880]);
                hold(ax2,'off');
            end
            
            if length(app.NotGerminated)==0
                yline(ax2,sum(app.NumGerminatedSpores)/numel(app.ImgDatastore.Files),':','Average Number of Germinated Spores','LineWidth',1);
                legend(ax,p2,'Edge touching tubes not removed','Location','northwest');
            else
                yline(ax2,sum(app.NumGerminatedSpores)/app.NumImages,':','Average Number of Germinated Spores','LineWidth',1);
                legend(ax,[p2 p1],{'Edge touching tubes not removed','No germinated spores detected'},'Location','northwest');
            end
            
            ax2.XTickLabel =app.T;
            xtickangle(ax2,45);
            title(ax2,'Number of Germinated Spores');
            
            Note=sprintf('PrecisionBio Version 1.1 @ 2020 Incotec \nComputational time: %0.5f s \nSelected image magnification: %s \nConversion parameter from length scale to pixels: %0.3f',toc,app.MagnificationDropDown.Value,app.Conversion.Value);
            annotation('textbox',[0.05 0.39 .4 .5],'String',Note,'FitBoxToText','on');
            saveas(h,fullname);
            delete(h);
            
            CompTime=sprintf('Computational time: %0.5f s',toc);
            Magnification=sprintf('Selected image magnification: %s',app.MagnificationDropDown.Value);
            ConvParameter=sprintf('Conversion parameter from length scale to pixels: %0.3f',app.Conversion.Value);
            writematrix('PrecisionBio Version 1.1 @ 2020 Incotec',fullnamexcel, 'Sheet',1,'Range','A1')
            writematrix(CompTime,fullnamexcel,'Sheet',1,'Range','A2');
            writematrix(Magnification,fullnamexcel,'Sheet',1,'Range','A3')
            writematrix(ConvParameter,fullnamexcel,'Sheet',1,'Range','A4')
            writematrix('File Name',fullnamexcel,'Sheet',1,'Range','A7')
            writematrix('Number of all Germinated Spores (*)',fullnamexcel,'Sheet',1,'Range','B7')
            writematrix('Number of used Germinated Spores (**)',fullnamexcel,'Sheet',1,'Range','C7')
            writematrix('Average Lengths',fullnamexcel,'Sheet',1,'Range','D7')
            writematrix('Comments',fullnamexcel,'Sheet',1,'Range','E7')
            [Xordered, firstsortorder] = sort(X);
            YorderedLength = Y(firstsortorder);
            ZorderedSpores = Z(firstsortorder);
            [XgermOrdered, firstsortorder] = sort(Xgerm);
            WorderedSpores = W(firstsortorder);
            for I=1:numel(X)
                [Img,info]=readimage(app.ImgDatastore,I);
                [filepathImg,nameImg,extImg] = fileparts(info.Filename);
                Folder=sprintf('Selected folder: %s',filepathImg);
                writematrix(Folder,fullnamexcel,'Sheet',1,'Range','A5')
                
                RangeA=sprintf('A%d',I+7);
                RangeB=sprintf('B%d',I+7);
                RangeC=sprintf('C%d',I+7);
                RangeD=sprintf('D%d',I+7);
                RangeE=sprintf('E%d',I+7);
                writematrix(nameImg,fullnamexcel,'Sheet',1,'Range',RangeA);
                writematrix(WorderedSpores(I),fullnamexcel,'Sheet',1,'Range',RangeB);
                writematrix(ZorderedSpores(I),fullnamexcel,'Sheet',1,'Range',RangeC);
                writematrix(YorderedLength(I),fullnamexcel,'Sheet',1,'Range',RangeD);
                
                if ismember(I,app.NotGerminated)==1
                    Comm=sprintf('No germinative tubes detected.');
                    writematrix(Comm,fullnamexcel,'Sheet',1,'Range',RangeE);
                elseif ismember(I,app.NotRemoved)==1
                    Comm=sprintf('Edge-touching tubes have not been removed.');
                    writematrix(Comm,fullnamexcel,'Sheet',1,'Range',RangeE);
                end
                
            end
            RangeAverageB=sprintf('B%d',numel(X)+10);
            RangeAverageBValue=sprintf('B%d',numel(X)+11);
            writematrix('Average Number of Germinated Spores',fullnamexcel,'Sheet',1,'Range',RangeAverageB);
            writematrix(app.AverageNofGerminatedSporesEditField.Value,fullnamexcel,'Sheet',1,'Range',RangeAverageBValue);
            RangeAverageD=sprintf('D%d',numel(X)+10);
            RangeAverageDValue=sprintf('D%d',numel(X)+11);
            if numel(app.NotRemoved)~=numel(app.ImgDatastore.Files)
                writematrix('Average Sample Length without images in which edge touching tubes not removed',fullnamexcel,'Sheet',1,'Range',RangeAverageD);
                writematrix(app.AverageLengthEditField.Value,fullnamexcel,'Sheet',1,'Range',RangeAverageDValue);
            end
            
            RangeAverageDD=sprintf('D%d',numel(X)+13);
            RangeAverageDDValue=sprintf('D%d',numel(X)+14);
            writematrix('Average Sample Length',fullnamexcel,'Sheet',1,'Range',RangeAverageDD)
            PixelLength2=sum(app.AllLengths);
            PixelSTD2=std(app.AllLengths);
            Average2=(PixelLength2/length(app.AllLengths))/app.Conversion.Value;
            STD2=PixelSTD2/app.Conversion.Value;
            if numel(app.ImgDatastore.Files)==1
                Newline=sprintf('%0.2f',Average2);
            else
                Newline=sprintf('%0.2f +/- %0.2f',Average2,STD2);
            end
            
            writematrix(Newline,fullnamexcel,'Sheet',1,'Range',RangeAverageDDValue)
            
            rangeExtra=sprintf('A%d',numel(X)+16);
            rangeExtra1=sprintf('A%d',numel(X)+17);
            writematrix('(*) All germinated spores detected in the picture',fullnamexcel,'Sheet',1,'Range',rangeExtra);
            writematrix('(**) Germinated spores used in computation of average length',fullnamexcel,'Sheet',1,'Range',rangeExtra1);
            
        end

        % Value changed function: Conversion
        function ConversionValueChanged(app, event)
            if app.AverageLengthEditField.Value~=""
                if numel(app.ImgDatastore.Files)==1
                    app.AverageLengthEditField.Value=sprintf('%0.2f',app.PixelLength/app.Conversion.Value);
                else
                    app.AverageLengthEditField.Value=sprintf('%0.2f +/- %0.2f',app.PixelLength/app.Conversion.Value,app.PixelSTD/app.Conversion.Value);
                end
            end
            
            cla(app.UIAxes);
            X=cat(2,app.Images,app.NotRemoved,app.NotGerminated);
            
            if app.ButtonGroup.SelectedObject==app.AverageLengthsButton
                Y=cat(2,app.Lengths/app.Conversion.Value,app.NotRemovedLengths/app.Conversion.Value,zeros(1,length(app.NotGerminated)));
                [Xordered, firstsortorder] = sort(X);
                YorderedLength = Y(firstsortorder);
                app.UIAxes.YLabel.String='Average Lengths';
                
                if isempty(Y)==0
                    UpYLim=ceil(max(Y)/100)*100;
                    app.UIAxes.YLim=[0,UpYLim];
                    
                    UpXLim=numel(app.ImgDatastore.Files)+0.2;
                    app.UIAxes.XLim=[0.8,UpXLim];
                    app.UIAxes.XTick=1:length(X);
                    
                    if numel(app.ImgDatastore.Files)<20
                        b1=bar(app.UIAxes,Xordered,YorderedLength,0.1,'FaceColor','flat');
                        for j=1:length(app.NotRemoved)
                            b1.CData(app.NotRemoved(j),:)=[0.4660 0.6740 0.1880];
                        end
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                        hold(app.UIAxes,'off');
                    else
                        plot(app.UIAxes,Xordered,YorderedLength);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.NotRemoved,app.NotRemovedLengths/app.Conversion.Value,'*','Color',[0.4660 0.6740 0.1880]);
                        hold(app.UIAxes,'off');
                    end
                    
                    if length(app.NotGerminated)==0
                        yline(app.UIAxes,sum(app.AllLengths)/(numel(app.ImgDatastore.Files)*app.Conversion.Value),':','Average Length','LineWidth',1);
                    else
                        yline(app.UIAxes,sum(app.AllLengths)/(app.NumImages*app.Conversion.Value),':','Average Length','LineWidth',1);
                    end
                end
                app.UIAxes.XTickLabel=app.T;
                xtickangle(app.UIAxes,45);
                
            elseif app.ButtonGroup.SelectedObject==app.NumberofGerminatedSporesButton
                app.UIAxes.YLabel.String='Number of Germinated Spores';
                Germ=cat(2,app.Images,app.NotRemoved);
                GermOrdered=sort(Germ);
                X=cat(2,GermOrdered,app.NotGerminated);
                Z=cat(2,app.NumGerminatedSpores,zeros(1,length(app.NotGerminated)));
                [Xordered, firstsortorder] = sort(X);
                ZorderedSpores = Z(firstsortorder);
                
                if isempty(Z)==0
                    if app.choose==1
                        UpZLim=ceil(max(Z)/10)*10;
                        app.UIAxes.YLim=[0,UpZLim];
                    elseif app.choose==2
                        UpZLim=ceil(max(Z)/100)*100;
                        app.UIAxes.YLim=[0,UpZLim];
                    end
                    
                    UpXLim=numel(app.ImgDatastore.Files)+0.2;
                    app.UIAxes.XLim=[0.8,UpXLim];
                    app.UIAxes.XTick=1:length(X);
                    
                    if numel(app.ImgDatastore.Files)<20
                        b1=bar(app.UIAxes,Xordered,ZorderedSpores,0.1);%,'FaceColor','flat');
                        %                     for j=1:length(app.NotRemoved)
                        %                         b1.CData(app.NotRemoved(j),:)=[0.4660 0.6740 0.1880];
                        %                     end
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                        hold(app.UIAxes,'off');
                    else
                        plot(app.UIAxes,Xordered,ZorderedSpores);
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.NotGerminated,zeros(1,length(app.NotGerminated)),'x','Color','r');
                        %                     hold(app.UIAxes,'on');
                        %                     plot(app.UIAxes,app.NotRemoved,app.NumNotRemovedSpores,'*','Color',[0.4660 0.6740 0.1880]);
                        hold(app.UIAxes,'off');
                    end
                    
                    if length(app.NotGerminated)==0
                        yline(app.UIAxes,sum(app.NumGerminatedSpores)/numel(app.ImgDatastore.Files),':','Average Number of Germinated Spores','LineWidth',1);
                    else
                        yline(app.UIAxes,sum(app.NumGerminatedSpores)/app.NumImages,':','Average Number of Germinated Spores','LineWidth',1);
                    end
                    app.UIAxes.XTickLabel=app.T;
                    xtickangle(app.UIAxes,45);
                    
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PrecisionBioUIFigure = uifigure('Visible', 'off');
            app.PrecisionBioUIFigure.Name = 'PrecisionBio';
            app.UploadImagesPanel = uipanel(app.PrecisionBioUIFigure);
            app.UploadImagesPanel.Title = 'Upload Images:';
            app.MagnificationDropDownLabel = uilabel(app.UploadImagesPanel);
            app.MagnificationDropDownLabel.Text = 'Magnification';
            app.MagnificationDropDown = uidropdown(app.UploadImagesPanel);
            app.MagnificationDropDown.Items = {'Select...', 'X100', 'X40'};
            app.MagnificationDropDown.ValueChangedFcn = createCallbackFcn(app, @MagnificationDropDownValueChanged, true);
            app.MagnificationDropDown.Value = 'Select...';
            app.UploadButton = uibutton(app.UploadImagesPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Enable = 'off';
            app.UploadButton.Text = 'Upload';
            app.Label_2 = uilabel(app.UploadImagesPanel);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = {'Warning! Analysis with magnification X40 is not '; 'recommended due to lower performance.'};
            app.SelectedFolderLabel = uilabel(app.UploadImagesPanel);
            app.SelectedFolderLabel.Text = 'Selected Folder:';
            app.SelectedFolderEditField = uieditfield(app.UploadImagesPanel, 'text');
            app.SelectedFolderEditField.Editable = 'off';
            app.GoButton = uibutton(app.UploadImagesPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Enable = 'off';
            app.GoButton.Text = 'Go';
            app.Label = uilabel(app.UploadImagesPanel);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.StopButton = uibutton(app.UploadImagesPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.unithlengthpixelsLabel = uilabel(app.UploadImagesPanel);
            app.unithlengthpixelsLabel.Text = '1 [unith length] :                                pixels';
            app.Conversion = uieditfield(app.UploadImagesPanel, 'numeric');
            app.Conversion.Limits = [0 Inf];
            app.Conversion.ValueChangedFcn = createCallbackFcn(app, @ConversionValueChanged, true);
            app.Conversion.Editable = 'off';
            app.Conversion.Enable = 'off';
            app.Conversion.Value = 1;
            app.Label_3 = uilabel(app.UploadImagesPanel);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '';
            app.ResultsPanel = uipanel(app.PrecisionBioUIFigure);
            app.ResultsPanel.Title = 'Results:';
            app.StandardDeviationLabel_2 = uilabel(app.ResultsPanel);
            app.StandardDeviationLabel_2.Text = '(+/- Standard Deviation)';
            app.StandardDeviationLabel = uilabel(app.ResultsPanel);
            app.StandardDeviationLabel.Text = '(+/- Standard Deviation)';
            app.SaveButton = uibutton(app.ResultsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.CommentsTextAreaLabel = uilabel(app.ResultsPanel);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.ResultsPanel);
            app.CommentsTextArea.Editable = 'off';
            app.CommentsTextArea.Enable = 'off';
            app.AverageLengthEditFieldLabel = uilabel(app.ResultsPanel);
            app.AverageLengthEditFieldLabel.Text = 'Average Length';
            app.AverageLengthEditField = uieditfield(app.ResultsPanel, 'text');
            app.AverageLengthEditField.Editable = 'off';
            app.AverageLengthEditField.Enable = 'off';
            app.AverageNofGerminatedSporesEditFieldLabel = uilabel(app.ResultsPanel);
            app.AverageNofGerminatedSporesEditFieldLabel.Text = 'Average N. of Germinated Spores';
            app.AverageNofGerminatedSporesEditField = uieditfield(app.ResultsPanel, 'text');
            app.AverageNofGerminatedSporesEditField.Editable = 'off';
            app.AverageNofGerminatedSporesEditField.Enable = 'off';
            app.Panel = uipanel(app.PrecisionBioUIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Analyzed Image')
            ylabel(app.UIAxes, 'Average Lengths')
            app.UIAxes.XLim = [0.9 10.1];
            app.UIAxes.YLim = [0 800];
            app.UIAxes.XTick = [1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.AverageLengthsButton = uiradiobutton(app.ButtonGroup);
            app.AverageLengthsButton.Enable = 'off';
            app.AverageLengthsButton.Text = 'Average Lengths';
            app.AverageLengthsButton.Value = true;
            app.NumberofGerminatedSporesButton = uiradiobutton(app.ButtonGroup);
            app.NumberofGerminatedSporesButton.Enable = 'off';
            app.NumberofGerminatedSporesButton.Text = 'Number of Germinated Spores';
            app.namesoftwareLabel = uilabel(app.PrecisionBioUIFigure);
            app.namesoftwareLabel.Text = 'PrecisionBio 1.1 @ 2020 Incotec, F. Magnabosco (francesca.magnabosco@croda.com)';
            app.Panel_2 = uipanel(app.PrecisionBioUIFigure);
            app.PanelScrollable = uipanel(app.PrecisionBioUIFigure);
            % Show the figure after all components are created
            app.PrecisionBioUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppAverageLengthNew3new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PrecisionBioUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PrecisionBioUIFigure)
        end
    end
end